classdef ElectrodeParameters2CSV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElectrodeParameterstoCSVGUIUIFigure  matlab.ui.Figure
        ExportButton_mw      matlab.ui.control.Button
        UITable_mw           matlab.ui.control.Table
        MicroCSVLabel        matlab.ui.control.Label
        CheckBox             matlab.ui.control.CheckBox
        Image3               matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        Image                matlab.ui.control.Image
        ExportButton         matlab.ui.control.Button
        MacroCSVLabel        matlab.ui.control.Label
        UITable              matlab.ui.control.Table
        EnterButton          matlab.ui.control.Button
        userInputElectrodes  matlab.ui.control.NumericEditField
        TotalnumberofmacroelectrodesLabel  matlab.ui.control.Label
        GUIabel              matlab.ui.control.Label
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%---'ElectrodeParameters2CSV' GUI---  
%
% Generates CSV files for macro and micro electrode parameters    
% from input found in patients' medical records    
%     
% Marielle L. Darwin | CU Anschutz Medical Campus | github.com/mldarwin
%
% Created: February 11 2022 | Last update: March 7 2022     
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  

    properties (Access = private)
        chanKey % chanKey is accessible to entire app now, call with app.chanKey
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Search current directiory for xls file and load as a table
            app.chanKey = readtable('Channel name key.xlsx');            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            numElectrodes = app.userInputElectrodes.Value;
            
            % Create empty array with 'numElectrodes' rows
            numRows = numElectrodes;
            
            % Create cell array with column names
            csvColumnNames = {'depEnum', 'startChan', 'stopChan',...
                'shortN', 'longN', 'hemi', 'numChan'};
            
            % Calculate column number from column names and create cell matrix
            dataMatrix = cell(numRows, length(csvColumnNames));
            
            % Fill in 1st column of table with number of electrodes
            dataTable = array2table(dataMatrix, 'VariableNames',...
                csvColumnNames);

            % Convert array to accept strings 
            dataTable.depEnum = num2cell((1:numElectrodes)'); 
            
            % Populate UI table
            app.UITable.Data = dataTable;
            
            % Empty cell array for each of the 3 user input variables            
            hemi = cell(numElectrodes, 1);
            numChan = cell(numElectrodes, 1);
            longN = cell(numElectrodes, 1);
            
            % Convert info in xlsx file to cell array
            chanKey_cell = table2cell(app.chanKey);
                        
            % Loop through numElectrodes to fill in values for each electrode
                for i = 1:numElectrodes
                    
                    % User input for longN, hemi, and numChan columns
                    prompt = {['Hemisphere: E',num2str(i)],...
                        ['Long name of brain region: E',num2str(i)],...
                        ['Number of channels: E',num2str(i)]};
                    dlgtitle = 'electrodeInfo';
                    dims = [1 35]; % dimensions of dlg box
                    definput = {'L','anterior hippocampus', '16'};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);           
                            
                        % Fill empty cells of hemi and numChan with dlg input                                            
                        hemi{i} = upper(answer{1});
                        numChan{i} = answer{3};   
                    
                        % Spell check longN
                            % Convert longN input to string
                            longNstr = string(answer(2));

                            % Make into tokenized document for spell check
                            strDoc = tokenizedDocument(longNstr);

                            % Correct spelling
                            ansCorrect_temp = correctSpelling(strDoc,...
                                'KnownWords',"amygdala",...
                                'KnownWords',"hippocampus");

                            % Convert correctly spelled longN to cell
                            ansCorrect = cellstr(joinWords(ansCorrect_temp));
                        
                        % Fill empty cells of longN 
                            % Extract user input of longN
                            longNanswer = answer(2);

                            % Attempt to find location of longN in logical array of chanKey_cell
                            TF_temp = matches(chanKey_cell,longNanswer);
                    
                            if TF_temp == 1
                                longN{i} = lower(answer{2});  % if no spell check was needed
                            else 
                                longN{i} = lower(ansCorrect); % if spell check was needed
                            end
                         
                         % Determine if further action is necessary for longN
                            % (i.e., need to enter new name, or spell check didn't work)
                           
                            % Location of spell checked longN in chanKey_cell
                            TF1 = matches(chanKey_cell,longN{i});
                            if TF1 == 0
                                % Dlg box
                                unknownAnswer = questdlg('Long name of electrode brain region not found for E:',num2str(i),...
                                    'Input new name', 'Re-enter name', 'Re-enter name');
                                
                                % Handle response
                                switch unknownAnswer % Add new region to excel sheet
                                    case 'Input new name'
                                        newPrompt = {['Long name of brain region: E',num2str(i)],...
                                            ['Short name of brain region for R hemisphere:']...
                                            ['Short name of brain region for L hemisphere:']};
                                        dlgtitle = 'Input new long name';
                                        dims = [1 35];
                                        definput = {'anterior hippocampus','RAH','LAH'};
                                        newAnswer = inputdlg(newPrompt, dlgtitle, dims, definput);

                                        % Add input to chanKey_cell, then excel sheet
                                        % Find # rows of chanKey_cell
                                        nRows = size(chanKey_cell,(1));
                                        % Add input to end of chanKeycell
                                        % Add new longN
                                        nA1 = lower(newAnswer{1});
                                        chanKey_cell((nRows+1),3) = {nA1}; %Converts char to cell
                                        % Add new L hemi shortN
                                        nA2 = upper(newAnswer{2});
                                        chanKey_cell((nRows+1),2) = {nA2};
                                        % Add new R hemi shortN
                                        nA3 = upper(newAnswer{3});
                                        chanKey_cell((nRows+1),1) = {nA3};

                                        % Export new version of chanKey_cell as excel file
                                        tempDir = uigetdir('Select Save Location of New Excel File');
                                        cd(tempDir);
                                        writecell(chanKey_cell,'Channel name key_new.xlsx');

                                        % Update longN
                                        longN{i} = {nA1};

                                    case 'Re-enter name' % Re-enter longN
                                        amendPrompt = {['Long name of brain region: E',num2str(i)]};
                                        dlgtitle = 'Input correct long name';
                                        dims = [1 35];
                                        definput = {'anterior hippocampus'};
                                        amendAnswer = inputdlg(amendPrompt, dlgtitle, dims, definput);

                                        % Update longN
                                        amendAnswer = lower(amendAnswer);
                                        longN{i} = {amendAnswer};
                                end
                                
                                    % Add to GUI table
                                    app.UITable.Data(i,5) = longN{1};
                            else
                                    app.UITable.Data(i,5) = longN{i};
                            end
                                        
                    % Calculate startChan column 
                    if i == 1
                        app.UITable.Data{i,2} = {1};
                    else
                        app.UITable.Data{i,2} = ...
                            {app.UITable.Data{i-1,2}{1}...
                            + str2double(numChan{i-1})};
                    end
                    
                    % Calculate stopChan column
                    app.UITable.Data{i,3} = ...
                        {(app.UITable.Data{i,2}{1}...
                        + str2double(numChan{i}))-1};
                    
                    % Determine shortN from longN input
                        % Location of spell checked longN in chanKey_cell
                        search_temp = longN{i};
                        search = char(search_temp{1});
                        TF2 = matches(chanKey_cell,search);
                    
                        % Row and column coordinates of longN
                        [row,col] = find(TF2==1);

                        % shortN is either 1 or 2 column(s) to the left
                            % in chanKey_cell, depending on hemisphere
                        if hemi{i} == 'R'
                        shortN_loc = chanKey_cell(row,(col-1));
                        else 
                        shortN_loc = chanKey_cell(row,(col-2));    
                        end

                        % Add to GUI table
                        app.UITable.Data(i,4) = {shortN_loc};
                end 
                
              % Add to GUI table
              app.UITable.Data(:,6) = hemi;
              app.UITable.Data(:,7) = numChan;
   
% Enable export button and MW checkbox
app.ExportButton.Enable = 'on';
app.CheckBox.Enable = 'on';                 
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
        % Export as CSV file and save 
        tempDir = uigetdir('Select Save Location');
        cd(tempDir);
        writetable(app.UITable.Data,'macroChanInfo.csv');
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            
            % Create empty array with 8 rows
            numRows_mw = 8;
            
            % Create cell array with column names
            csvColumnNames_mw = {'depEnum', 'mwInd', 'chanNum',...
                'shortN', 'longN', 'hemi'};
            
            % Calculate column number from column names and create cell matrix
            dataMatrix_mw = cell(numRows_mw, length(csvColumnNames_mw));
            
            % Fill in 1st column of table with number of electrodes
            dataTable_mw = array2table(dataMatrix_mw, ...
                'VariableNames', csvColumnNames_mw);
            
            % Populate UI table
            app.UITable_mw.Data = dataTable_mw;

            % Dialog box
            prompt = {'Which depth electrode contains the microwire?'};
            dlgtitle = 'Microwire';
            dims = [1 35]; % dimensions of dlg box
            definput = {'1'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            % Fill empty cells with dlg input
            app.UITable_mw.Data(:,1) = answer;
            
            % Convert info in macro table to cell array
            macroTable = app.UITable.Data;
            macroTable_cell = table2cell(macroTable);
           
            % Populate micro GUI table 
            for m = 1:numRows_mw
                % mwInd column
                app.UITable_mw.Data{m,2} = {m};
                
                % chanNum column
                if m == 1
                   app.UITable_mw.Data{m,3} = {257};
                else
                   app.UITable_mw.Data{m,3} = ...
                       {(app.UITable_mw.Data{m-1,3}{1})+1};
                end
                
                %shortN, longN, and hemi columns
                    % Column of macroTable_cell with depEnums
                    depEnum_loc = string(macroTable_cell(:,1));

                    % Find depEnum that matches 'answer'
                    TF_mw = matches(depEnum_loc,answer);

                    % Row and column coordinates of 'answer'
                    [row,~] = find(TF_mw==1);

                    % Find shortN, longN, and hemi of mw depEnum
                    shortN_loc = macroTable_cell(row,4);
                    longN_loc = macroTable_cell(row,5);
                    hemi_loc = macroTable_cell(row,6);

                    % Add to micro GUI table
                    app.UITable_mw.Data(m,4) = shortN_loc;
                    app.UITable_mw.Data(m,5) = longN_loc;
                    app.UITable_mw.Data(m,6) = hemi_loc;

            end  

% Enable export button
app.ExportButton_mw.Enable = 'on';
        end

        % Button pushed function: ExportButton_mw
        function ExportButton_mwPushed(app, event)
        % Export as CSV file and save 
        tempDir = uigetdir('Select Save Location');
        cd(tempDir);
        writetable(app.UITable_mw.Data,'microChanInfo.csv'); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElectrodeParameterstoCSVGUIUIFigure = uifigure('Visible', 'off');
            app.ElectrodeParameterstoCSVGUIUIFigure.Name = 'Electrode Parameters to CSV GUI';
            app.GUIabel = uilabel(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.GUIabel.Text = 'Electrode Parameters to CSV';
            app.TotalnumberofmacroelectrodesLabel = uilabel(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.TotalnumberofmacroelectrodesLabel.Text = 'Total number of macro electrodes';
            app.userInputElectrodes = uieditfield(app.ElectrodeParameterstoCSVGUIUIFigure, 'numeric');
            app.EnterButton = uibutton(app.ElectrodeParameterstoCSVGUIUIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.UITable = uitable(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.UITable.ColumnName = {'depEnum'; 'startChan'; 'stopChan'; 'shortN'; 'longN'; 'hemi'; 'numChan'};
            app.UITable.RowName = {};
            app.MacroCSVLabel = uilabel(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.MacroCSVLabel.Text = 'Macro CSV';
            app.ExportButton = uibutton(app.ElectrodeParameterstoCSVGUIUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.Image = uiimage(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.Image.ImageSource = 'nlx.jpeg';
            app.Image2 = uiimage(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.Image2.ImageSource = 'csv3.jpg';
            app.Image3 = uiimage(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.Image3.ImageSource = 'arrowword.jpeg.png';
            app.CheckBox = uicheckbox(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = 'Micro electrode data';
            app.MicroCSVLabel = uilabel(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.MicroCSVLabel.Text = 'Micro CSV';
            app.UITable_mw = uitable(app.ElectrodeParameterstoCSVGUIUIFigure);
            app.UITable_mw.ColumnName = {'depEnum'; 'mwInd'; 'chanNum'; 'shortN'; 'longN'; 'hemi'};
            app.UITable_mw.RowName = {};
            app.ExportButton_mw = uibutton(app.ElectrodeParameterstoCSVGUIUIFigure, 'push');
            app.ExportButton_mw.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_mwPushed, true);
            app.ExportButton_mw.Enable = 'off';
            app.ExportButton_mw.Text = 'Export';
            % Show the figure after all components are created
            app.ElectrodeParameterstoCSVGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElectrodeParameters2CSV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElectrodeParameterstoCSVGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElectrodeParameterstoCSVGUIUIFigure)
        end
    end
end